classdef tutorialApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        HomeButton              matlab.ui.control.Button
        CommsLabel              matlab.ui.control.Label
        MovePoseButton          matlab.ui.control.Button
        YawEditField_2          matlab.ui.control.NumericEditField
        YawEditField_2Label     matlab.ui.control.Label
        PitchEditField_2        matlab.ui.control.NumericEditField
        PitchEditField_2Label   matlab.ui.control.Label
        RollEditField_2         matlab.ui.control.NumericEditField
        RollEditField_2Label    matlab.ui.control.Label
        zEditField_2            matlab.ui.control.NumericEditField
        zEditField_2Label       matlab.ui.control.Label
        yEditField_2            matlab.ui.control.NumericEditField
        yEditField_2Label       matlab.ui.control.Label
        xEditField_2            matlab.ui.control.NumericEditField
        xEditField_2Label       matlab.ui.control.Label
        OrientationradLabel_2   matlab.ui.control.Label
        PositionmLabel_2        matlab.ui.control.Label
        TargetStateLabel        matlab.ui.control.Label
        CurrentStateLabel       matlab.ui.control.Label
        YawEditField            matlab.ui.control.NumericEditField
        YawEditFieldLabel       matlab.ui.control.Label
        PitchEditField          matlab.ui.control.NumericEditField
        PitchEditFieldLabel     matlab.ui.control.Label
        RollEditField           matlab.ui.control.NumericEditField
        RollEditFieldLabel      matlab.ui.control.Label
        zEditField              matlab.ui.control.NumericEditField
        zEditFieldLabel         matlab.ui.control.Label
        yEditField              matlab.ui.control.NumericEditField
        yEditFieldLabel         matlab.ui.control.Label
        xEditField              matlab.ui.control.NumericEditField
        xEditFieldLabel         matlab.ui.control.Label
        j6EditField             matlab.ui.control.NumericEditField
        j6EditFieldLabel        matlab.ui.control.Label
        j5EditField             matlab.ui.control.NumericEditField
        j5EditFieldLabel        matlab.ui.control.Label
        j4EditField             matlab.ui.control.NumericEditField
        j4EditFieldLabel        matlab.ui.control.Label
        j3EditField             matlab.ui.control.NumericEditField
        j3EditFieldLabel        matlab.ui.control.Label
        j2EditField             matlab.ui.control.NumericEditField
        j2EditFieldLabel        matlab.ui.control.Label
        j1EditField             matlab.ui.control.NumericEditField
        j1EditFieldLabel        matlab.ui.control.Label
        OrientationradLabel     matlab.ui.control.Label
        PositionmLabel          matlab.ui.control.Label
        JointradLabel           matlab.ui.control.Label
        RobotCalibrationButton  matlab.ui.control.Button
        MATLABAppControlInterfaceforNiryoNedRobotLabel  matlab.ui.control.Label
        LearningModeButton      matlab.ui.control.StateButton
        SimulateRobotButton     matlab.ui.control.Button
        UpdateIPsButton         matlab.ui.control.Button
        ROSShutdownButton       matlab.ui.control.Button
        ROSInitialiseButton     matlab.ui.control.Button
        PCIPEditField           matlab.ui.control.EditField
        PCIPEditFieldLabel      matlab.ui.control.Label
        NedIPEditField          matlab.ui.control.EditField
        NedIPLabel              matlab.ui.control.Label
    end

    %% Matlab App for Niryo Ned Control by Praneel Chand
    %% Credits and thanks to Nicolas Guy, Niryo, & The Brainary 
    properties (Access = private)
        %insert global variable names here 
        ned % ned robot model from urdf file
        curJoints % current joint angles in radians (joints 1 to 6
        curPosition % current position coordintes (x, y, z)
        curOrient % current orientation in radians (roll, pitch, yaw)
        learnCmd % learning mode command
        jointStates %subscription to read joint states topic (joint angles)
        robotStates %subscription to read robot states topic (position, rpy, quaternion)
        nedState % subscription to read ned states topic
        nedCmd % publish to ned commands topic
        learnClient % learning mode client
        ikClient % inverse kinematics client
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %clear all
            %clc
            
            NedIP = app.NedIPEditField.Value
            PCIP = app.PCIPEditField.Value
            setenv('ROS_MASTER_URI',strcat('http://',NedIP,':11311')) %IP of the Ned WIFI hotspot
            setenv('ROS_IP',PCIP) %IP of the computer WIFI hotspot
            app.ned = importrobot('ned.urdf'); % import robot simulation model
        end

        % Button pushed function: ROSShutdownButton
        function ROSShutdownButtonPushed(app, event)
            rosshutdown;
        end

        % Button pushed function: ROSInitialiseButton
        function ROSInitialiseButtonPushed(app, event)
            % initialise ROS with IP address
            ipaddress = strcat('http://',app.NedIPEditField.Value,':11311'); %IP of the Ned WIFI hotspot
            rosinit(ipaddress)
            pause(2);
            %turn on learning mode after ROS initialise
            app.learnCmd = rospublisher("/niryo_robot/learning_mode/state") ;
            %velMsg = rosmessage(app.learnCmd);
            %velMsg.Data = true;
            %send(app.learnCmd,velMsg)
            %pause(1)
            %app.LearningModeButton.Text = "Learning Mode ON";
            
            % add other publish and subrscribe topics
            % subscribe to read joint states topic
            app.jointStates = rossubscriber('/joint_states');
            % show initial joint states (angles)
            %show joint angles on display
            pause(1)
            scandata = receive(app.jointStates,10);
            app.j1EditField.Value = scandata.Position(1);
            app.j2EditField.Value = scandata.Position(2);
            app.j3EditField.Value = scandata.Position(3);
            app.j4EditField.Value = scandata.Position(4);
            app.j5EditField.Value = scandata.Position(5);
            app.j6EditField.Value = scandata.Position(6);
            % subscribe to read robot states (position, rpy, quaternion)
            app.robotStates = rossubscriber('/niryo_robot/robot_state');
            pause(1)
            clear scandata
            scandata = receive(app.robotStates,10);
            app.xEditField.Value = scandata.Position.X;
            app.yEditField.Value = scandata.Position.Y;
            app.zEditField.Value = scandata.Position.Z;
            app.RollEditField.Value = scandata.Rpy.Roll;
            app.PitchEditField.Value = scandata.Rpy.Pitch;
            app.YawEditField.Value = scandata.Rpy.Yaw;
            % create learning mode client and publish value 1 for learning mode on 
            app.learnClient = rossvcclient('/niryo_robot/learning_mode/activate');
            ReqMsg = rosmessage(app.learnClient);
            ReqMsg.Value = 1; % 1 for learning mode on, 0 for learning mode off.
            learningAct = call(app.learnClient,ReqMsg);
            app.LearningModeButton.Text = "Learning Mode ON";
            pause(1)
            %publish and subscribe to other topics and services
            
        end

        % Button pushed function: UpdateIPsButton
        function UpdateIPsButtonPushed(app, event)
            NedIP = app.NedIPEditField.Value
            PCIP = app.PCIPEditField.Value
            setenv('ROS_MASTER_URI',strcat('http://',NedIP,':11311')) %IP of the Ned WIFI hotspot
            setenv('ROS_IP',PCIP) %IP of the computer WIFI hotspot
        end

        % Button pushed function: SimulateRobotButton
        function SimulateRobotButtonPushed(app, event)
            %axes = show(app.ned);
            %axes.CameraPositionMode = 'auto';
            %axes.XLim = [-0.5 0.5];
            %axes.YLim = [-0.5 0.5];
            %axes.ZLim = [-0.1 0.55];
            %showdetails(app.ned)
            %inititial_configuration = ned.homeConfiguration;
            %config = homeConfiguration(ned)
            % read current joint angles
            %joint_states = rossubscriber('/joint_states');
            pause(1)
            scandata = receive(app.jointStates,10);
            %update robot position in simulation
            configSoln = homeConfiguration(app.ned);
            if ~isempty(scandata.Position)
                configSoln = homeConfiguration(app.ned);
                for i = 1:6
                configSoln(i).JointPosition = scandata.Position(i);
                end
            end
            %scandata.Position
            %configSoln.JointPosition
            axes = show(app.ned,configSoln); %configSoln is 1x6 struct with 2 fields (joint name and joint angle)
            axes.CameraPositionMode = 'auto';
            axes.XLim = [-0.5 0.5];
            axes.YLim = [-0.5 0.5];
            axes.ZLim = [-0.1 0.55];
            %show joint angles on display
            app.j1EditField.Value = configSoln(1).JointPosition;
            app.j2EditField.Value = configSoln(2).JointPosition;
            app.j3EditField.Value = configSoln(3).JointPosition;
            app.j4EditField.Value = configSoln(4).JointPosition;
            app.j5EditField.Value = configSoln(5).JointPosition;
            app.j6EditField.Value = configSoln(6).JointPosition;
            pause(1)
            % read current robot state - position & rpy
            clear scandata
            scandata = receive(app.robotStates,10);
            app.xEditField.Value = scandata.Position.X;
            app.yEditField.Value = scandata.Position.Y;
            app.zEditField.Value = scandata.Position.Z;
            app.RollEditField.Value = scandata.Rpy.Roll;
            app.PitchEditField.Value = scandata.Rpy.Pitch;
            app.YawEditField.Value = scandata.Rpy.Yaw;
        end

        % Value changed function: LearningModeButton
        function LearningModeButtonValueChanged(app, event)
            value = app.LearningModeButton.Value;
            %app.learnCmd = rospublisher("/niryo_robot/learning_mode/state") ;
            %velMsg = rosmessage(app.learnCmd);
            ReqMsg = rosmessage(app.learnClient);
            if value==1
                %velMsg.Data = false; % turn off learning mode
                ReqMsg.Value = 0; % 1 for learning mode on, 0 for learning mode off.
                %learningAct = call(app.learnClient,ReqMsg);
                app.LearningModeButton.Text = "Learning Mode OFF"
            else
                %velMsg.Data = true; % turn on learning mode
                ReqMsg.Value = 1; % 1 for learning mode on, 0 for learning mode off.
                %learningAct = call(app.learnClient,ReqMsg);
                app.LearningModeButton.Text = "Learning Mode ON"
            end
            %send(app.learnCmd,velMsg)
            learningAct = call(app.learnClient,ReqMsg);
            pause(2)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            rosshutdown;
            clear all
            clc
        end

        % Button pushed function: MovePoseButton
        function MovePoseButtonPushed(app, event)
            % create ik client and use it to calulate joint angles  
            app.ikClient = rossvcclient('/niryo_robot/kinematics/inverse');
            ReqMsg = rosmessage(app.ikClient);
            % read target pose from GUI inputs
            ReqMsg.Pose.Position.X = app.xEditField_2.Value;
            ReqMsg.Pose.Position.Y = app.yEditField_2.Value;
            ReqMsg.Pose.Position.Z = app.zEditField_2.Value;
            ReqMsg.Pose.Rpy.Roll = app.RollEditField_2.Value;
            ReqMsg.Pose.Rpy.Pitch = app.PitchEditField_2.Value;
            ReqMsg.Pose.Rpy.Yaw = app.YawEditField_2.Value;
            % calculate inverse kinematics
            ik = call(app.ikClient,ReqMsg);
            %make Ned move with the inverse kinematic service solution and
            %foward kinematic controller (joint angles)
            app.nedState = rossubscriber("/niryo_robot_follow_joint_trajectory_controller/state");
            app.nedCmd = rospublisher("/niryo_robot_follow_joint_trajectory_controller/command");
            CmdMsg = rosmessage(app.nedCmd);
            CmdPoint = rosmessage('trajectory_msgs/JointTrajectoryPoint');
            CmdPoint.Positions = ik.Joints; %We get the Joints goal from the IK service
            CmdPoint.Velocities = zeros(1,6);
            CmdPoint.Accelerations = zeros(1,6);
            CmdPoint.TimeFromStart = ros.msg.Duration(3);
            CmdMsg.Header.Stamp = rostime("now") + rosduration(0.05);
            CmdMsg.JointNames = {'joint_1', 'joint_2', 'joint_3', 'joint_4', 'joint_5', 'joint_6'};
            CmdMsg.Points = CmdPoint;
            send(app.nedCmd,CmdMsg);
            pause(1)
            %update simulated position of robot.
            SimulateRobotButtonPushed(app, event)
        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
            %move the robot to the home position
            % create ik client and use it to calulate joint angles  
            app.ikClient = rossvcclient('/niryo_robot/kinematics/inverse');
            ReqMsg = rosmessage(app.ikClient);
            % target pose for home
            ReqMsg.Pose.Position.X = 0.135;
            ReqMsg.Pose.Position.Y = 0.000;
            ReqMsg.Pose.Position.Z = 0.213;
            ReqMsg.Pose.Rpy.Roll = 0.000;
            ReqMsg.Pose.Rpy.Pitch = 0.751;
            ReqMsg.Pose.Rpy.Yaw = 0.000;
            % calculate inverse kinematics
            ik = call(app.ikClient,ReqMsg);
            %make Ned move with the inverse kinematic service solution and
            %foward kinematic controller (joint angles)
            app.nedState = rossubscriber("/niryo_robot_follow_joint_trajectory_controller/state");
            app.nedCmd = rospublisher("/niryo_robot_follow_joint_trajectory_controller/command");
            CmdMsg = rosmessage(app.nedCmd);
            CmdPoint = rosmessage('trajectory_msgs/JointTrajectoryPoint');
            CmdPoint.Positions = ik.Joints; %We get the Joints goal from the IK service
            CmdPoint.Velocities = zeros(1,6);
            CmdPoint.Accelerations = zeros(1,6);
            CmdPoint.TimeFromStart = ros.msg.Duration(3);
            CmdMsg.Header.Stamp = rostime("now") + rosduration(0.05);
            CmdMsg.JointNames = {'joint_1', 'joint_2', 'joint_3', 'joint_4', 'joint_5', 'joint_6'};
            CmdMsg.Points = CmdPoint;
            send(app.nedCmd,CmdMsg);
            pause(1)
            %update simulated position of robot.
            SimulateRobotButtonPushed(app, event)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.NedIPLabel = uilabel(app.UIFigure);
            app.NedIPLabel.Text = 'Ned IP:';
            app.NedIPEditField = uieditfield(app.UIFigure, 'text');
            app.NedIPEditField.Value = '192.168.137.143';
            app.PCIPEditFieldLabel = uilabel(app.UIFigure);
            app.PCIPEditFieldLabel.Text = 'PC IP:';
            app.PCIPEditField = uieditfield(app.UIFigure, 'text');
            app.PCIPEditField.Value = '192.168.137.1';
            app.ROSInitialiseButton = uibutton(app.UIFigure, 'push');
            app.ROSInitialiseButton.ButtonPushedFcn = createCallbackFcn(app, @ROSInitialiseButtonPushed, true);
            app.ROSInitialiseButton.Text = 'ROS Initialise';
            app.ROSShutdownButton = uibutton(app.UIFigure, 'push');
            app.ROSShutdownButton.ButtonPushedFcn = createCallbackFcn(app, @ROSShutdownButtonPushed, true);
            app.ROSShutdownButton.Text = 'ROS Shutdown';
            app.UpdateIPsButton = uibutton(app.UIFigure, 'push');
            app.UpdateIPsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateIPsButtonPushed, true);
            app.UpdateIPsButton.Text = 'Update IPs';
            app.SimulateRobotButton = uibutton(app.UIFigure, 'push');
            app.SimulateRobotButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateRobotButtonPushed, true);
            app.SimulateRobotButton.Text = 'Simulate Robot';
            app.LearningModeButton = uibutton(app.UIFigure, 'state');
            app.LearningModeButton.ValueChangedFcn = createCallbackFcn(app, @LearningModeButtonValueChanged, true);
            app.LearningModeButton.Text = 'Learning Mode';
            app.MATLABAppControlInterfaceforNiryoNedRobotLabel = uilabel(app.UIFigure);
            app.MATLABAppControlInterfaceforNiryoNedRobotLabel.Text = 'MATLAB App: Control Interface for Niryo Ned Robot';
            app.RobotCalibrationButton = uibutton(app.UIFigure, 'push');
            app.RobotCalibrationButton.Text = 'Robot Calibration';
            app.JointradLabel = uilabel(app.UIFigure);
            app.JointradLabel.Text = 'Joint (rad)';
            app.PositionmLabel = uilabel(app.UIFigure);
            app.PositionmLabel.Text = 'Position (m)';
            app.OrientationradLabel = uilabel(app.UIFigure);
            app.OrientationradLabel.Text = 'Orientation (rad)';
            app.j1EditFieldLabel = uilabel(app.UIFigure);
            app.j1EditFieldLabel.Text = 'j1:';
            app.j1EditField = uieditfield(app.UIFigure, 'numeric');
            app.j1EditField.ValueDisplayFormat = '%.3f';
            app.j2EditFieldLabel = uilabel(app.UIFigure);
            app.j2EditFieldLabel.Text = 'j2:';
            app.j2EditField = uieditfield(app.UIFigure, 'numeric');
            app.j2EditField.ValueDisplayFormat = '%.3f';
            app.j3EditFieldLabel = uilabel(app.UIFigure);
            app.j3EditFieldLabel.Text = 'j3:';
            app.j3EditField = uieditfield(app.UIFigure, 'numeric');
            app.j3EditField.ValueDisplayFormat = '%.3f';
            app.j4EditFieldLabel = uilabel(app.UIFigure);
            app.j4EditFieldLabel.Text = 'j4:';
            app.j4EditField = uieditfield(app.UIFigure, 'numeric');
            app.j4EditField.ValueDisplayFormat = '%.3f';
            app.j5EditFieldLabel = uilabel(app.UIFigure);
            app.j5EditFieldLabel.Text = 'j5:';
            app.j5EditField = uieditfield(app.UIFigure, 'numeric');
            app.j5EditField.ValueDisplayFormat = '%.3f';
            app.j6EditFieldLabel = uilabel(app.UIFigure);
            app.j6EditFieldLabel.Text = 'j6:';
            app.j6EditField = uieditfield(app.UIFigure, 'numeric');
            app.j6EditField.ValueDisplayFormat = '%.3f';
            app.xEditFieldLabel = uilabel(app.UIFigure);
            app.xEditFieldLabel.Text = 'x:';
            app.xEditField = uieditfield(app.UIFigure, 'numeric');
            app.xEditField.ValueDisplayFormat = '%.3f';
            app.yEditFieldLabel = uilabel(app.UIFigure);
            app.yEditFieldLabel.Text = 'y:';
            app.yEditField = uieditfield(app.UIFigure, 'numeric');
            app.yEditField.ValueDisplayFormat = '%.3f';
            app.zEditFieldLabel = uilabel(app.UIFigure);
            app.zEditFieldLabel.Text = 'z:';
            app.zEditField = uieditfield(app.UIFigure, 'numeric');
            app.zEditField.ValueDisplayFormat = '%.3f';
            app.RollEditFieldLabel = uilabel(app.UIFigure);
            app.RollEditFieldLabel.Text = 'Roll:';
            app.RollEditField = uieditfield(app.UIFigure, 'numeric');
            app.RollEditField.ValueDisplayFormat = '%.3f';
            app.PitchEditFieldLabel = uilabel(app.UIFigure);
            app.PitchEditFieldLabel.Text = 'Pitch:';
            app.PitchEditField = uieditfield(app.UIFigure, 'numeric');
            app.PitchEditField.ValueDisplayFormat = '%.3f';
            app.YawEditFieldLabel = uilabel(app.UIFigure);
            app.YawEditFieldLabel.Text = 'Yaw:';
            app.YawEditField = uieditfield(app.UIFigure, 'numeric');
            app.YawEditField.ValueDisplayFormat = '%.3f';
            app.CurrentStateLabel = uilabel(app.UIFigure);
            app.CurrentStateLabel.Text = 'Current State:';
            app.TargetStateLabel = uilabel(app.UIFigure);
            app.TargetStateLabel.Text = 'Target State:';
            app.PositionmLabel_2 = uilabel(app.UIFigure);
            app.PositionmLabel_2.Text = 'Position (m)';
            app.OrientationradLabel_2 = uilabel(app.UIFigure);
            app.OrientationradLabel_2.Text = 'Orientation (rad)';
            app.xEditField_2Label = uilabel(app.UIFigure);
            app.xEditField_2Label.Text = 'x:';
            app.xEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.xEditField_2.ValueDisplayFormat = '%.3f';
            app.yEditField_2Label = uilabel(app.UIFigure);
            app.yEditField_2Label.Text = 'y:';
            app.yEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.yEditField_2.ValueDisplayFormat = '%.3f';
            app.zEditField_2Label = uilabel(app.UIFigure);
            app.zEditField_2Label.Text = 'z:';
            app.zEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.zEditField_2.ValueDisplayFormat = '%.3f';
            app.RollEditField_2Label = uilabel(app.UIFigure);
            app.RollEditField_2Label.Text = 'Roll:';
            app.RollEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.RollEditField_2.ValueDisplayFormat = '%.3f';
            app.PitchEditField_2Label = uilabel(app.UIFigure);
            app.PitchEditField_2Label.Text = 'Pitch:';
            app.PitchEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.PitchEditField_2.ValueDisplayFormat = '%.3f';
            app.YawEditField_2Label = uilabel(app.UIFigure);
            app.YawEditField_2Label.Text = 'Yaw:';
            app.YawEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.YawEditField_2.ValueDisplayFormat = '%.3f';
            app.MovePoseButton = uibutton(app.UIFigure, 'push');
            app.MovePoseButton.ButtonPushedFcn = createCallbackFcn(app, @MovePoseButtonPushed, true);
            app.MovePoseButton.Text = 'Move Pose';
            app.CommsLabel = uilabel(app.UIFigure);
            app.CommsLabel.Text = 'Comms:';
            app.HomeButton = uibutton(app.UIFigure, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Text = 'Home';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tutorialApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end